classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LLISTab                         matlab.ui.container.Tab
        RPSgenerationPanel              matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        PulsehwidthnsEditFieldLabel     matlab.ui.control.Label
        PulsehwidthnsEditField          matlab.ui.control.NumericEditField
        PrenoisenormDropDownLabel       matlab.ui.control.Label
        PrenoisenormDropDown            matlab.ui.control.DropDown
        NoisesamplesEditFieldLabel      matlab.ui.control.Label
        NoisesamplesEditField           matlab.ui.control.NumericEditField
        WindowmultiplierEditFieldLabel  matlab.ui.control.Label
        WindowmultiplierEditField       matlab.ui.control.NumericEditField
        ShowRPSCheckBox                 matlab.ui.control.CheckBox
        GenerateRPSButton               matlab.ui.control.Button
        MSEvaluepresetButtonGroup       matlab.ui.container.ButtonGroup
        SinglevalueButton               matlab.ui.control.RadioButton
        RangeButton                     matlab.ui.control.RadioButton
        MSEminEditField                 matlab.ui.control.NumericEditField
        MSEmaxEditField                 matlab.ui.control.NumericEditField
        max_dBLabel                     matlab.ui.control.Label
        min_dBLabel                     matlab.ui.control.Label
        LLISrecievermodelPanel          matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        FilterbandmultEditFieldLabel    matlab.ui.control.Label
        FilterbandmultEditField         matlab.ui.control.NumericEditField
        ADCbitsEditFieldLabel           matlab.ui.control.Label
        ADCbitsEditField                matlab.ui.control.NumericEditField
        en_quantize                     matlab.ui.control.CheckBox
        en_sampling                     matlab.ui.control.CheckBox
        FsamplingEditFieldLabel         matlab.ui.control.Label
        FsamplingEditField              matlab.ui.control.NumericEditField
        PostADCnormDropDownLabel        matlab.ui.control.Label
        PostADCnormDropDown             matlab.ui.control.DropDown
        UpsamlefactorEditFieldLabel     matlab.ui.control.Label
        UpsamlefactorEditField          matlab.ui.control.NumericEditField
        ShowDRPSCheckBox                matlab.ui.control.CheckBox
        TimesnapDropDownLabel           matlab.ui.control.Label
        TimesnapDropDown                matlab.ui.control.DropDown
        OffsetcountsEditFieldLabel      matlab.ui.control.Label
        OffsetcountsEditField           matlab.ui.control.NumericEditField
        UpsamletechniqueDropDownLabel   matlab.ui.control.Label
        UpsamletechniqueDropDown        matlab.ui.control.DropDown
        ProcessDRPSButton               matlab.ui.control.Button
        JittersnapEditFieldLabel        matlab.ui.control.Label
        JittersnapEditField             matlab.ui.control.NumericEditField
        FeaturesTab                     matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        TSNEPanel                       matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        DistanceLabel                   matlab.ui.control.Label
        DistanceDropDown                matlab.ui.control.DropDown
        PerplexityLabel                 matlab.ui.control.Label
        PerplexityEditField             matlab.ui.control.NumericEditField
        EarlyexagerationEditFieldLabel  matlab.ui.control.Label
        EarlyexagerationEditField       matlab.ui.control.NumericEditField
        LearningrateEditFieldLabel      matlab.ui.control.Label
        LearningrateEditField           matlab.ui.control.NumericEditField
        ApplySNECheckBox                matlab.ui.control.CheckBox
        ProcessTSNEButton               matlab.ui.control.Button
        GradientScatter                 matlab.ui.control.Button
        MeasureinSNECheckBox            matlab.ui.control.CheckBox
        MeasureinFSCheckBox             matlab.ui.control.CheckBox
        MeasuretechniqueDropDownLabel   matlab.ui.control.Label
        MeasuretechniqueDropDown        matlab.ui.control.DropDown
        NnearestEditFieldLabel          matlab.ui.control.Label
        NnearestEditField               matlab.ui.control.NumericEditField
        KAlienneighboursEditFieldLabel  matlab.ui.control.Label
        KAlienneighboursEditField       matlab.ui.control.NumericEditField
        FeatureselectionPanel           matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        StepLabel                       matlab.ui.control.Label
        StepEditField                   matlab.ui.control.NumericEditField
        CVtypeDropDownLabel             matlab.ui.control.Label
        CVtypeDropDown                  matlab.ui.control.DropDown
        FoldsLabel                      matlab.ui.control.Label
        FoldsDropDown                   matlab.ui.control.DropDown
        MaxdimensionsSpinnerLabel       matlab.ui.control.Label
        MaxdimensionsSpinner            matlab.ui.control.Spinner
        ReductionalgorithmLabel         matlab.ui.control.Label
        ReductionalgorithmDropDown      matlab.ui.control.DropDown
        MetricsfractionLabel            matlab.ui.control.Label
        MetricsfractionEditField        matlab.ui.control.NumericEditField
        NneighboursEditFieldLabel       matlab.ui.control.Label
        NneighboursEditField            matlab.ui.control.NumericEditField
        ClassifiertypeDropDownLabel     matlab.ui.control.Label
        ClassifiertypeDropDown          matlab.ui.control.DropDown
        UseGPUCheckBox                  matlab.ui.control.CheckBox
        L1SpinnerLabel                  matlab.ui.control.Label
        L1Spinner                       matlab.ui.control.Spinner
        L2SpinnerLabel                  matlab.ui.control.Label
        L2Spinner                       matlab.ui.control.Spinner
        L3SpinnerLabel                  matlab.ui.control.Label
        L3Spinner                       matlab.ui.control.Spinner
        SavesubsetsCheckBox             matlab.ui.control.CheckBox
        ShowgraphicsCheckBox_5          matlab.ui.control.CheckBox
        AnalyzeButton                   matlab.ui.control.Button
        manualselectionCheckBox         matlab.ui.control.CheckBox
        ResultslistListBoxLabel         matlab.ui.control.Label
        ResultslistListBox              matlab.ui.control.ListBox
        ResultsavailableLabel           matlab.ui.control.Label
        ResultsavailableLamp            matlab.ui.control.Lamp
        GraphicsButton_2                matlab.ui.control.Button
        L4Label                         matlab.ui.control.Label
        L4Spinner                       matlab.ui.control.Spinner
        ShowconfusionmxCheckBox         matlab.ui.control.CheckBox
        ShowmapsCheckBox                matlab.ui.control.CheckBox
        CheckResButton                  matlab.ui.control.Button
        CurvesfordimsButton             matlab.ui.control.Button
        kforrectificationEditFieldLabel  matlab.ui.control.Label
        kforrectificationEditField      matlab.ui.control.NumericEditField
        DecisionmodeDropDownLabel       matlab.ui.control.Label
        DecisionmodeDropDown            matlab.ui.control.DropDown
        HeuristicDropDownLabel          matlab.ui.control.Label
        HeuristicDropDown               matlab.ui.control.DropDown
        SaveclassifiersCheckBox         matlab.ui.control.CheckBox
        EditFieldLabel                  matlab.ui.control.Label
        filterCheckBox                  matlab.ui.control.CheckBox
        searchField                     matlab.ui.control.EditField
        TransformsPanel                 matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        NoneCheckBox                    matlab.ui.control.CheckBox
        LampN                           matlab.ui.control.Lamp
        FFTCheckBox                     matlab.ui.control.CheckBox
        AFFTCheckBox                    matlab.ui.control.CheckBox
        CWTCheckBox                     matlab.ui.control.CheckBox
        ACWTCheckBox                    matlab.ui.control.CheckBox
        WTCheckBox                      matlab.ui.control.CheckBox
        PCACheckBox                     matlab.ui.control.CheckBox
        waveLabel                       matlab.ui.control.Label
        waveletcwtDropDown              matlab.ui.control.DropDown
        waveLabel_2                     matlab.ui.control.Label
        waveletacwtDropDown             matlab.ui.control.DropDown
        waveLabel_3                     matlab.ui.control.Label
        waveletwtDropDown               matlab.ui.control.DropDown
        LampFFT                         matlab.ui.control.Lamp
        LampAFFT                        matlab.ui.control.Lamp
        LampCWT                         matlab.ui.control.Lamp
        LampACWT                        matlab.ui.control.Lamp
        LampWT                          matlab.ui.control.Lamp
        LampPCA                         matlab.ui.control.Lamp
        GenerateFVSButton               matlab.ui.control.Button
        ClearDirButton                  matlab.ui.control.Button
        savetomatCheckBox               matlab.ui.control.CheckBox
        savetocsvCheckBox               matlab.ui.control.CheckBox
        autoregenfvs                    matlab.ui.control.CheckBox
        DCACheckBox                     matlab.ui.control.CheckBox
        LampDCA                         matlab.ui.control.Lamp
        traindcaCheckBox                matlab.ui.control.CheckBox
        trainpcaCheckBox                matlab.ui.control.CheckBox
        ClassificationTab               matlab.ui.container.Tab
        TestclassifierButton            matlab.ui.control.Button
        TestsetselectionButtonGroup     matlab.ui.container.ButtonGroup
        signatureButton                 matlab.ui.control.RadioButton
        filesButton                     matlab.ui.control.RadioButton
        userinterfaceButton             matlab.ui.control.RadioButton
        ClassifiertemplateButtonGroup   matlab.ui.container.ButtonGroup
        readfromUIButton                matlab.ui.control.RadioButton
        featuremapfileButton            matlab.ui.control.RadioButton
        DatasetPanel                    matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        ICRpathEditFieldLabel           matlab.ui.control.Label
        ICRpathEditField                matlab.ui.control.EditField
        CurrentdatasetEditFieldLabel    matlab.ui.control.Label
        CurrentdatasetEditField         matlab.ui.control.EditField
        ChangeButton                    matlab.ui.control.Button
        OpenICRsButton                  matlab.ui.control.Button
        OpendatasetButton               matlab.ui.control.Button
        autoregenicrs                   matlab.ui.control.CheckBox
        GenerateICRsbaseButton          matlab.ui.control.Button
        ShowICRSCheckBox                matlab.ui.control.CheckBox
        ICRListBox                      matlab.ui.control.ListBox
        ObjectsButtonGroup              matlab.ui.container.ButtonGroup
    end

    
    properties (Access = private)
        dataset_directory   % project directory
        src_icrs_directory  % директория с ИХР-файлами
        llis_model          % object SystemModel
        asp_selection = [1 2 3];    % выбор ракурсов для вывода циклограмм
        exp_id              % идентификатор текущего эксперимента
        t_types             % точка хранения настроек преобразования (см update_fvs_panel() )
        meta_data
    end
    
    methods (Access = private)
        
        % загрузка ИХР нескольких объектов из mat файлов
        function [icrs_out, meta_out] = get_all_icrs(app)
            if ~iscell(app.ICRListBox.Value)
                error('Select multiple objects')
            end
            n_obj = length(app.ICRListBox.Value);    
            icrs_out = cell(1,n_obj);   % матрица под ИХР
            meta_out = cell(1, n_obj);  % матрица под метаданные
            for i_obj = 1:n_obj     % загрузка ИХР для каждого объекта
                
                mat_file = [app.dataset_directory '\icrs\' app.ICRListBox.Value{i_obj} '.mat'];
                if app.autoregenicrs.Value
                    delete(mat_file)
                end
                
                if exist(mat_file,'file')==2 % если файл не существует или требуется регенерация
                    in_data = load(mat_file,'icrs','meta');   % загрузить ИХР из mat файла
                    icrs = in_data.icrs; meta = in_data.meta; % 
                else
                    filelist = dir([ app.src_icrs_directory ...
                        '\' app.ICRListBox.Value{i_obj} '\*.icr']);         % получить структуру списка файлов из заданной папки
                    for i = 1:length(filelist)                          % собрать список полных имен файлов из структуры
                        filelist(i).name = horzcat(filelist(i).folder, '\', filelist(i).name); 
                    end
                    [icrs, meta] = RPTools.open_icr_file(...
                        {filelist(1:end).name}.');              % получить ИХР и метаданные
                    save(mat_file,'icrs','meta');               % сохранить ИХР в mat-файл
                    disp(['Сохранение базы ИХР в ' mat_file]);
                end
                
                icrs_out{i_obj}=icrs;
                meta_out{i_obj}=meta;
            end
        end
        
        % формирование ДП
        function [rps] = get_rps(app, icrs, meta)
            n_obj = length(icrs);
            imp_hwidth_ns = app.PulsehwidthnsEditField.Value;       % длительность импульса
            prenoise_rp_norm = app.PrenoisenormDropDown.Value;      % нормировка
            if isempty(app.llis_model) 
                app.llis_model = SystemModel();                             % создать объект класса
            end
            n_icrs = cellfun(@(mx) size(mx,1), icrs);
            rps_j = app.llis_model.lls_model(vertcat(icrs{:}), imp_hwidth_ns, 'n', {'n' 'n'});
            if ~strcmp(prenoise_rp_norm, 'none')
                rps_j = RPTools.norm_rp(rps_j,prenoise_rp_norm);
            end
            rps = mat2cell(rps_j,n_icrs).';
            
            obj_lst = app.ICRListBox.Value; show_asp = app.asp_selection;
            if app.ShowRPSCheckBox.Value                    % построение графика ДП
                f = figure('Name','ДП', 'color', 'white','WindowStyle','docked');
                for i_obj = 1:n_obj                         % формирование ДП 
                    window_ns = app.llis_model.resol_ns*app.llis_model.rp_dim;
                    label_ns = linspace(0, ceil(window_ns/10)*10, ceil(window_ns/10)+1);   % задать метрические значения для оси x
                    subplot(1,n_obj,i_obj);    RPTools.plot_rps(gca, rps{i_obj}, meta{i_obj}, show_asp); 
                    title([ obj_lst{i_obj} ': \tau_{имп}=',num2str(imp_hwidth_ns),'нс'],'FontSize', 10 );   % название графика
                    xticks(label_ns/app.llis_model.resol_ns); xticklabels(label_ns); xlabel('t, нс');       % настройка оси x
                end
                savefig(f, [app.dataset_directory '\extras\' 'rps_s(t' num2str(imp_hwidth_ns) ').fig'])
            end
            
        end
        
        % добавление аддитивного шума к выборкам ДП
        function [rps_n, meta_n] = get_noise_rps(app, rps, meta)
            if app.SinglevalueButton.Value
                mse_noise = app.MSEmaxEditField.Value;
            else
                mse_noise = [app.MSEminEditField.Value, app.MSEmaxEditField.Value];
            end
            n_samples = app.NoisesamplesEditField.Value;
            mult_timeframe = app.WindowmultiplierEditField.Value;
            app.exp_id = ['t' num2str(app.PulsehwidthnsEditField.Value,'%02.1f') '_mse' num2str(max(mse_noise)) ];
            
            n_obj = length(rps);
            rps_n = cell(1, n_obj); meta_n = cell(1, n_obj);
            for i_obj = 1:n_obj     % Добавление шума 
                [rps_n{i_obj}, meta_n{i_obj}] = RPTools.add_noise(...
                    rps{i_obj}, meta{i_obj}, mse_noise, n_samples, mult_timeframe);
            end
            
            obj_lst = app.ICRListBox.Value; show_asp = app.asp_selection;
            if app.ShowRPSCheckBox.Value                    % построение графика ДП
                f = figure('Name','ДП+шум', 'color', 'white','WindowStyle','docked');
                for i_obj = 1:n_obj     % Добавление шума 
                    window_ns = app.llis_model.resol_ns*app.llis_model.rp_dim;
                    label_ns = linspace(0, ...
                        ceil(window_ns*mult_timeframe/10)*10, ...
                        ceil(window_ns*mult_timeframe/10)+1);   % задать значения для оси x
                    subplot(1,n_obj,i_obj);    
                    RPTools.plot_rps(gca, rps_n{i_obj},meta_n{i_obj},show_asp); 
                    title([ obj_lst{i_obj} ': \sigma_n=',num2str(mse_noise,'%.2e ')],'FontSize', 10 )
                    xticks(label_ns/app.llis_model.resol_ns); xticklabels(label_ns); xlabel('t, нс')
                end
                savefig(f, [app.dataset_directory '\extras\' 'rps_n(' app.exp_id ').fig'])
            end 
        end
        
        % сохранить состояние приложения
        function save_app_state(app)
            llis_state.llis_model = app.llis_model;
            llis_state.src_icrs_directory = app.src_icrs_directory;
            llis_state.dataset_name = app.CurrentdatasetEditField.Value;
            llis_state.dataset_dir = app.dataset_directory;
            llis_state.obj_list = app.ICRListBox.Items;
            llis_state.obj_sel = app.ICRListBox.Value;
            
            llis_state.imp_ns = app.PulsehwidthnsEditField.Value;
            llis_state.prenoisenorm = app.PrenoisenormDropDown.Value;
            llis_state.noise_mse = app.MSEmaxEditField.Value;
            llis_state.n_samples = app.NoisesamplesEditField.Value;
            llis_state.noise_multiplier = app.WindowmultiplierEditField.Value;
            
            llis_state.filter_band_mult = app.FilterbandmultEditField.Value;
            llis_state.quantiz_on = app.en_quantize.Value;
            llis_state.quantiz_bit = app.ADCbitsEditField.Value;
            llis_state.sampling_on = app.en_sampling.Value;
            llis_state.sampling_mult = app.FsamplingEditField.Value;
            llis_state.postnorm = app.PostADCnormDropDown.Value;
            llis_state.timesnap = app.TimesnapDropDown.Value;
            llis_state.offset = app.OffsetcountsEditField.Value;
            llis_state.exp_id = app.exp_id;
            llis_state.t_types = app.t_types;       
            
            fs_analysis.step = app.StepEditField.Value;
            fs_analysis.max_dim = app.MaxdimensionsSpinner.Value;
            fs_analysis.folds = app.FoldsDropDown.Value;
            fs_analysis.reduction = app.ReductionalgorithmDropDown.Value;
            fs_analysis.nmx = app.MetricsfractionEditField.Value;
            fs_analysis.save_subsets = app.SavesubsetsCheckBox.Value;
            
            save([app.dataset_directory '\settings.mat'],'llis_state','fs_analysis')
        end
        
        % загрузить состояние приложения
        function load_app_state(app)
            if exist([app.dataset_directory '\settings.mat'],'file')==2
                load([app.dataset_directory '\settings.mat'],'llis_state')
                try
                    app.src_icrs_directory = llis_state.src_icrs_directory;
                    app.CurrentdatasetEditField.Value = llis_state.dataset_name;
                    app.dataset_directory = llis_state.dataset_dir;
                    app.open_icrs()
                    app.ICRListBox.Items = llis_state.obj_list;
                    app.ICRListBox.Value = llis_state.obj_sel;
                    
                    app.PulsehwidthnsEditField.Value = llis_state.imp_ns;
                    app.PrenoisenormDropDown.Value = llis_state.prenoisenorm;
                    app.MSEmaxEditField.Value = llis_state.noise_mse;
                    app.NoisesamplesEditField.Value = llis_state.n_samples;
                    app.WindowmultiplierEditField.Value = llis_state.noise_multiplier;
                    
                    app.FilterbandmultEditField.Value = llis_state.filter_band_mult;
                    app.en_quantize.Value = llis_state.quantiz_on;
                    app.ADCbitsEditField.Value = llis_state.quantiz_bit;
                    app.en_sampling.Value = llis_state.sampling_on;
                    app.FsamplingEditField.Value = llis_state.sampling_mult;
                    app.PostADCnormDropDown.Value = llis_state.postnorm;
                    app.TimesnapDropDown.Value = llis_state.timesnap;
                    app.OffsetcountsEditField.Value = llis_state.offset;
                    app.exp_id = llis_state.exp_id;
                    app.load_fvs_panel(horzcat(llis_state.t_types(:,2)))         % инициализировать чекбоксы панель значениями
%                     app.load_fvs_panel([llis_state.t_types{:,2}])         % инициализировать чекбоксы панель значениями
                    app.llis_model = llis_state.llis_model;
                    
                catch ME
                    if strcmp(ME.identifier,'MATLAB:nonExistentField')  % если файл settings.mat несовместим, просто открыть ИХР
                        warning('Файл настроек несовместим')            % дать предупреждение
                        app.src_icrs_directory = [app.dataset_directory '\icrs'];
                        app.open_icrs();    
                    else
                        rethrow(ME)
                    end
                end
            else    % нет файла настроек
                warning('No settings file');    
                app.src_icrs_directory = [app.dataset_directory '\icrs'];
                app.open_icrs();
            end
        end
        
        % Загрузить состояние панели
        function load_fvs_panel(app,t_types)
            % на входе массив для checkbox'ов
            for i_t_type = 1:size(t_types, 1)
                try
                    app.t_types{i_t_type, 2}.Value = t_types{1};
                catch ME
                    if strcmp(ME.identifier,'MATLAB:nonExistentField')  % если файл settings.mat несовместим, просто открыть ИХР
                        warning('Файл настроек несовместим')            % дать предупреждение
                        app.open_icrs();    
                    else
                        app.t_types{i_t_type, 2}.Value = false;
                    end
                end
            end
%             app.NoneCheckBox.Value  = t_types{1};
%             app.FFTCheckBox.Value   = t_types{2};
%             app.AFFTCheckBox.Value  = t_types{3};
%             app.CWTCheckBox.Value   = t_types{4};
%             app.ACWTCheckBox.Value  = t_types{5};
%             app.WTCheckBox.Value    = t_types{6};
%             app.PCACheckBox.Value   = t_types{7};
%             app.DCACheckBox.Value   = t_types{8};
%             %TODO добавить сохранение и загрузку типов вейвлетов 
        end
        
        % открыть ихр
        function open_icrs(app)
            if app.src_icrs_directory==0, return; end
            app.ICRpathEditField.Value = app.src_icrs_directory;
            dir_list = dir(app.src_icrs_directory);                             % формирование списка объектов из списка папок с ИХР
            dirFlags = [dir_list.isdir];        % флаг папок
            dir_list = dir_list(dirFlags);      % выбрать только папки
            app.ICRListBox.Items = {dir_list(3:end).name};      % объекты;
            app.ICRListBox.Value = {dir_list(3:end).name};      % выбрать все по умолчанию
            
            if ischar(app.dataset_directory) || isstring(app.dataset_directory)
            else
                app.dataset_directory = split(app.src_icrs_directory,'\');          % выделения названий папок до /icrs
                app.CurrentdatasetEditField.Value = app.dataset_directory{end-1};   % выделить имя датасета
                app.dataset_directory = ['.\',app.CurrentdatasetEditField.Value];   % формирование относительного адреса датасета
            end
        
        end
        
        function snr_db_str = get_snr_db(app, mse_val)
            imp_hwidth_ns = app.PulsehwidthnsEditField.Value;
            switch app.PrenoisenormDropDown.Value
                case {'energy','mod'}
                    icr_dim = 200;
                    resol_ns = 0.1;
                    
                    gauss_hwidth = ceil(imp_hwidth_ns/resol_ns);
                    hw2sig = 2.35;       % отношение ширины по половине к сигма
                    sig = gauss_hwidth/hw2sig; % отсчетов в одной сигма
                    imp_dim = sig*8;
                    rp_dim = icr_dim+imp_dim;
                    rp_energy = 1;
                    noise_energy = mse_val^2*rp_dim;
                    snr_db_str = num2str(10*log10(rp_energy/noise_energy),'%05.1f dB');
                otherwise
                    snr_db_str='--.- dB';
            end
            
        end
    
    
        % Тракт ЛЛС
        function rps_samples = get_drps(app, rps_samples, meta_n)
            obj_lst = app.ICRListBox.Value;
            n_obj = length(obj_lst);
            imp_hwidth_ns = app.PulsehwidthnsEditField.Value;
            lp_mult = app.FilterbandmultEditField.Value;
            show_asp = app.asp_selection;
            time_snap_mode = app.TimesnapDropDown.Value;
            w_offset = app.OffsetcountsEditField.Value;
            dgtl_rp_norm = app.PostADCnormDropDown.Value;
            
            imp_sigma_ns = imp_hwidth_ns/app.llis_model.hwidth2sig; % сигма ЗИ, нс 
            imp_band_mhz = 1/(2*imp_sigma_ns)*1e3;                  % полоса имп. по 3 sigma 
            lp_band_mhz = imp_band_mhz*lp_mult;                     % полоса фильтра НЧ
            
            fd_mhz_q = {'n' 'n'};       % АЦП
            if app.en_sampling.Value, fd_mhz_q{1} = lp_band_mhz*app.FsamplingEditField.Value; end
            if app.en_quantize.Value, fd_mhz_q{2} = app.ADCbitsEditField.Value; end
%             fprintf('(%s) %s\n', datetime('now','Format','HH:mm:ss'), ['ЦП: Fзи(3sig)=' num2str(imp_band_mhz) 'МГц, FФ=' num2str(lp_band_mhz) 'МГц, Fд=' num2str(fd_mhz_q{1}) 'МГц'])
            
            n_rps = cellfun(@(mx) size(mx,1), rps_samples);
            rps_samples = vertcat(rps_samples{:});
            rps_samples(rps_samples<0)=0;
            rps_samples = app.llis_model.lls_model(...
                rps_samples, 'n' , lp_band_mhz, fd_mhz_q);     % Фильтр+АЦП
            if ~strcmp(dgtl_rp_norm, 'none')              % если выполняется нормировка, то
                rps_samples = single(rps_samples);    % ДП переводятся в 32-битное представление
                rps_samples = RPTools.norm_rp(rps_samples, dgtl_rp_norm);  % нормировка
            end
            upsamp_rate = app.UpsamlefactorEditField.Value;
            interp_method = app.UpsamletechniqueDropDown.Value;
            if ~strcmp(interp_method,'none') 
                rps_samples = RPTools.interpol(rps_samples,1,upsamp_rate,interp_method);
                rps_samples = RPTools.rp_time_snap(rps_samples,time_snap_mode,w_offset*upsamp_rate,...
                    'shift_law','Uniform','random_shift',app.JittersnapEditField.Value*upsamp_rate);  % временная привязка
                rps_samples = rps_samples(:,1:upsamp_rate:end); 
            else
                rps_samples = RPTools.rp_time_snap(rps_samples,time_snap_mode,w_offset);  % временная привязка
            end    
            dig_rp_dim = size(rps_samples,2);
            fprintf('\t компенс. вр. сдв: [интерп.%s(%d/1)][_^_%s][децим.1/%d]\n',interp_method,upsamp_rate,time_snap_mode,upsamp_rate)
            rps_samples = mat2cell(rps_samples,n_rps).';
            
            rps2fvs(app,1, rps_samples, meta_n);
            
            if app.ShowDRPSCheckBox.Value
                f = figure('Name','Цифр.ДП', 'color', 'white','WindowStyle','docked');
                for i_obj = 1:n_obj
                    label_ns = linspace(0, ...
                        ceil(dig_rp_dim*app.llis_model.T_sampling_ns/10)*10, ...
                        ceil(dig_rp_dim*app.llis_model.T_sampling_ns/10)+1);   % задать значения для оси x
                    subplot(1,n_obj,i_obj);    
                    RPTools.plot_rps(gca, rps_samples{i_obj}, meta_n{i_obj}, show_asp); 
                    if strcmp(fd_mhz_q{2},'n'), ylim([0 0.7])
                    elseif strcmp(dgtl_rp_norm,'none'), ylim([0 2^fd_mhz_q{2}])
                    else, ylim([0 1])
                    end
                    xticks(label_ns/app.llis_model.T_sampling_ns); xticklabels(label_ns);
                    title([obj_lst{i_obj} ': f_д=' num2str(fd_mhz_q{1}) ' МГц, '],'FontSize', 10 )
                    xlabel('t, нс')
                end
                savefig(f, [app.dataset_directory '/extras/rps_d(' app.exp_id ').fig'])
            end
            
        end
        
        % проверить наличие преобразований, обновить интерфейс
        function regen_vect = update_fvs_panel(app)
            % обновляет массив t_types и возвращает вектор задействованных
            % преобразований
            %   НАСТРОЙКИ ПРЕОБРАЗОВАНИЙ
            %   название вкл/выкл                 ук-ль на лампу  тип базиса
            app.t_types={...         
                'none',  app.NoneCheckBox.Value,  app.LampN       'x'; ...
                'fft',   app.FFTCheckBox.Value,   app.LampFFT     'x'; ...
                'afft',  app.AFFTCheckBox.Value,  app.LampAFFT    'x'; ...
                'cwt',   app.CWTCheckBox.Value,   app.LampCWT     app.waveletcwtDropDown.Value  ;... 
                'acwt',  app.ACWTCheckBox.Value,  app.LampACWT    app.waveletacwtDropDown.Value ;...
                'wt',    app.WTCheckBox.Value,    app.LampWT      app.waveletwtDropDown.Value   ;...
                'pca',   app.PCACheckBox.Value,   app.LampPCA     'x';
                'dca',   app.DCACheckBox.Value,   app.LampDCA     'x'...
                };
            n_transforms = size(app.t_types,1);     % число преобразований
            % Лампы
            regen_vect = zeros(n_transforms,1);
            if strcmp(app.MSEminEditField.Enable,'on') 
                mse_max = max(app.MSEmaxEditField.Value, app.MSEminEditField.Value);
            else 
                mse_max = app.MSEmaxEditField.Value;
            end
            app.exp_id = ['t' num2str(app.PulsehwidthnsEditField.Value,'%02.1f') ...
                '_mse' num2str(mse_max) ]; % обновить id эксперимента
            for i_t_type = 1:n_transforms
                app.t_types{i_t_type,3}.Color='g';      % лампа по умолчанию зеленая
                app.t_types{i_t_type,3}.Enable=true;    % лампа по умолчанию активная
                
                t_type = app.t_types{i_t_type, 1};  % имя преобразования
                wavenm = app.t_types{i_t_type, 4};  % имя базисной функции
                if app.savetocsvCheckBox.Value      % проверка существования csv файла
                    csv_filename = [ app.dataset_directory '/fvs/' t_type '-' wavenm '_' app.exp_id '.csv'];
                    if exist(csv_filename,'file')~=2            % нет файла -> лампа красная 
                        app.t_types{i_t_type, 3}.Color='r';
                    end
                end
                if app.savetomatCheckBox.Value      % проверка существования mat файла
                    mat_filename = [ app.dataset_directory '/fvs/' t_type '-' wavenm '_' app.exp_id '.mat'];
                    if exist(mat_filename,'file')~=2            % нет файла -> лампа красная
                        app.t_types{i_t_type, 3}.Color='r'; 
                    end
                end
                
                if ~app.t_types{i_t_type,2} % нет галочки выбора преобразования  
                    app.t_types{i_t_type,3}.Enable=false;           % -> лампочка не активная
                elseif or(... есть галочка и
                        all(app.t_types{i_t_type, 3}.Color==[1 0 0]),...    % красная лампа или
                        app.autoregenfvs.Value)           %                       % автореген
                    regen_vect(i_t_type) = 1;       % -> надо регенерировать
                end
                
            end
            
        end
        
        % выдать ВП (сформировать или прочитать из файла
        function [fvs_cell, meta] = get_fvs(app)
            % пройти по заданным в t_types преобразованиям
            % сфомрировать структуру
            %TODO исправить генерацию выборки на каждое преобразование
            regen = app.update_fvs_panel();
            n_types = size(app.t_types,1);
            fvs_cell = cell(n_types,1);
            app.save_app_state()
            if any(regen) % если потребуется реген, подготовить ДП до цикла
                if or(app.autoregenfvs.Value, all(app.LampN.Color==[1 0 0]))               % если автореген, то сформировать новые реализации ДП
                    [icrs, meta] = app.get_all_icrs();              % загрузить ИХР
                    [rps] = app.get_rps(icrs, meta);                % выполнить формирование ДП
                    [rps_n, meta] = app.get_noise_rps(rps, meta);   % добавить шумовые выборки
                    [rps_samples] = app.get_drps(rps_n,meta);       % фильтрация и АЦП
                else
                    [rps_samples, meta] = app.load_fvs(1);  % если нет авторегена, то реализации из Амплитудно-вр. отсчетов
                end
            end
            
            for i_t_type = 1:n_types    % пройти по преобразованиям
                if app.t_types{i_t_type,2}
                    if regen(i_t_type) % если нужно обновить хотя бы один набор ВП, все остальные тоже регеним, т.к. один набор шумовых выборок
                        try
                            [fvs_cell{i_t_type}, meta] = app.rps2fvs(i_t_type,rps_samples, meta);    % сформировать ВП
                        catch ME
                            switch ME.identifier
                                case 'MATLAB:DDDTREE:expectedPositive'
                                    warning('КВП не может быть расчитано для такого короткого сигнала')
                                    switch i_t_type
                                        case 4, app.CWTCheckBox.Value=0; 
                                        case 5, app.ACWTCheckBox.Value=0;
                                    end
                                    continue
                                case 'MATLAB:wavedec:expectedPositive'
                                    warning('ВП не может быть расчитано для такого короткого сигнала')
                                    app.WTCheckBox.Value=0; continue
                                otherwise 
                                    rethrow(ME)
                            end
                        end
                    else
                        [fvs_cell{i_t_type}, meta] = app.load_fvs(i_t_type);        % прочитать из файла
                    end
                end
            end
            app.update_fvs_panel();
            app.save_app_state();
        end
        
        % прочитать вектора признаков из файла
        function [fvs,meta] = load_fvs(app,i_t_type)
            t_type = app.t_types{i_t_type,1};   % номер преобразования
            wavenm = app.t_types{i_t_type,4};   % имя функции
            in_filename = [ app.dataset_directory '/fvs/' t_type '-' wavenm '_' app.exp_id '.mat'];
            in_data = load(in_filename);        
            fvs = in_data.fvs; 
            meta = in_data.meta;
        end
        
        % сформировать и записать в файл
        function [fvs,meta] = rps2fvs(app,i_t_type, rps_samples, meta)
            t_type = app.t_types{i_t_type,1};               % прочитать 
            wavenm = app.t_types{i_t_type,4}; 
            n_samples = app.NoisesamplesEditField.Value;
            %TODO: изменить алгоритм, убрав костыль
%             if ~exist("app.llis_model","var"), app.llis_model = SystemModel(); end
            if strcmp(t_type, "dca")
                trgs = vertcat(meta{:});
                if app.traindcaCheckBox.Value
                    app.llis_model.ldca_reset();    % сбросить коэффициенты
                end
                [fvs_mx, coef_map] = app.llis_model.transform_wmap(...
                vertcat(rps_samples{:}), t_type, {trgs.name}.');   % выполнить преобразование
            elseif strcmp(t_type, "pca")
                if app.trainpcaCheckBox.Value
                    app.llis_model.pca_reset();
                end
                [fvs_mx, coef_map] = app.llis_model.transform_wmap(...
                vertcat(rps_samples{:}), t_type);   % выполнить преобразование
            else
                [fvs_mx, coef_map] = app.llis_model.transform_wmap(...
                vertcat(rps_samples{:}), t_type, wavenm);   % выполнить преобразование
            end
            fvs_mx = single(fvs_mx);                        % ухудшить точность вычислений
            feat_header = RPTools.get_feature_map(t_type, coef_map);
            if app.savetocsvCheckBox.Value
                csv_filename = [ app.dataset_directory '\fvs\' t_type '-' wavenm '_' app.exp_id '.csv'];
                fprintf('(%s) ', datetime('now','Format','HH:mm:ss'))
                RPTools.save_csv(fvs_mx, vertcat(meta{:}), feat_header, csv_filename);
            end
            if app.savetomatCheckBox.Value
                mat_filename = [ app.dataset_directory '\fvs\' t_type '-' wavenm '_' app.exp_id '.mat'];
                n_obj = length(meta);
                fvs = cell(1, n_obj);
                i_start = 1;
                for i_obj = 1:n_obj
                    n_items = size(meta{i_obj}, 1);
                    i_stop = i_start+n_items-1;
                    fvs{i_obj} = fvs_mx(i_start:i_stop,:);
                    i_start = i_stop+1;
                end
                save(mat_filename,'fvs','meta','feat_header','n_samples',"wavenm");
                fprintf('Сохранено %d ВП в файл %s\n', size(fvs_mx,1), mat_filename)
            end
        end
        
        % выполнить tsne-анализ данных расположенных по ячейкам
        function tsne_cell(app, fvs, meta)
        % выполнить tsne анализ данных и сохранить фигуры
        % вектора признаков передаются по ячейкам преобразований, мета-данные
        % одни на все вектора признаков
            e_exag = floor(app.EarlyexagerationEditField.Value);  % значение раннего преувеличения
            perplex = floor(app.PerplexityEditField.Value);       % значение perplexity
            learn_rate = floor(app.LearningrateEditField.Value);       % значение perplexity
            dist_type = app.DistanceDropDown.Value;
            n_types = size(app.t_types, 1);
            for i_type = 1:n_types
                if app.t_types{i_type,2}                % если это преобразование задействовано
                    t_type = app.t_types{i_type, 1};    % извлечь название перобразования
                    fprintf('(%s) %s', datetime('now','Format','HH:mm:ss'),[ 'Анализ ' t_type ])   
                    [features,classes] = QualityCheck.unwrap_cell_data(fvs{i_type},meta);   % перевести данные из ячеек в матрицу                    
                    if app.ApplySNECheckBox.Value
                    Y = tsne(features, 'Exaggeration', e_exag, 'Perplexity', perplex, 'LearnRate',learn_rate,'Distance',dist_type);       
                    app.meta_data = meta;
                    f_handle = figure('Name',t_type,'color', 'white','WindowStyle','docked');
                    gscatter(Y(:,1),Y(:,2),classes);    % сформировать и отобразить график
                    % сохранение массива данных
                    y_out = num2cell(Y,2);              % перевод матрицы в n_ячеек 
                    tsne_data = vertcat(meta{:});                 % передать метаданные в выводимую перем.
                    [tsne_data(:).tsne_scatter] = deal(y_out{:});     % запись данных tsne в выв. перем.
                    save_filename = [app.dataset_directory '/results/tsne(' app.exp_id '_' t_type  ')'];
                    save([save_filename '.mat'], "tsne_data","e_exag","perplex");
                    titlestr = ['TSNE analysis (' replace(app.exp_id,'_',' ') ', ' t_type '), P=' num2str(perplex) ', E=' num2str(e_exag)];
                    if ~strcmp(app.MeasuretechniqueDropDown.Value, 'none')
                        if app.MeasureinFSCheckBox.Value
                            q_src = SystemModel.nn_separability(features, classes, ...
                                'n_nearest', app.NnearestEditField.Value,...
                                'm_technique', app.MeasuretechniqueDropDown.Value,...
                                'k_alien', app.KAlienneighboursEditField.Value);
                            titlestr = [titlestr ', A^{', app.MeasuretechniqueDropDown.Value,'}_{src}=' num2str(q_src) ''];
                            fprintf('\tA(src,%s)= %f', t_type, q_src)
                        end                        
                        if app.MeasureinSNECheckBox.Value
                            q_sep = SystemModel.tsne_separability([save_filename '.mat'], ...
                                'm_technique', app.MeasuretechniqueDropDown.Value,...
                                'n_nearest', app.NnearestEditField.Value, ...
                                'k_alien', app.KAlienneighboursEditField.Value);
                            titlestr = [titlestr ', A^{', app.MeasuretechniqueDropDown.Value,'}_{sne}=' num2str(q_sep)];
                            fprintf('\tA(sne,%s)= %f', t_type, q_sep)
                        end   
                    end
                    % графика
                    title(titlestr)
                    savefig(f_handle, [ save_filename '.fig'])   % сохранить результат анализа
                    dcm_obj = datacursormode(f_handle);     % достать указатель на датакурсор (делать только после сохранения фигуры)
                    set(dcm_obj, 'UpdateFcn', ...
                        @(empt,event_obj) app.tsne_datapoint_popup(empt,event_obj))
                    
                    else
                        q_src = SystemModel.nn_separability(features, classes, ...
                                'n_nearest', app.NnearestEditField.Value,...
                                'm_technique', app.MeasuretechniqueDropDown.Value,...
                                'k_alien', app.KAlienneighboursEditField.Value);
                        fprintf('\tA(src,%s)= %f', t_type, q_src)
                    end
                    fprintf('\n')
                end
            end
        end
        
        % запустить скрипт отбора признаков
        function run_crossval_script(app)
        %
        %            
            net_layers = [app.L1Spinner.Value, app.L2Spinner.Value, app.L3Spinner.Value, app.L4Spinner.Value];  % слои сети
            net_layers(net_layers==0)=[];
            if app.UseGPUCheckBox.Value, use_gpu='yes'; else, use_gpu='no'; end
            Crossvalidation.reduction(...
                'dset_folder_name',app.CurrentdatasetEditField.Value, ...       % dataset
                't_select_v', [app.t_types{:,2}], ...                            % маска выбора объектов
                't_bfuns', app.t_types(:,4), ...
                'reduction_method', app.ReductionalgorithmDropDown.Value, ...  % метод редукции
                'heuristic', app.HeuristicDropDown.Value,...
                'n_metrics_fraction', app.MetricsfractionEditField.Value, ...  % доля метрик
                'k_alien', app.kforrectificationEditField.Value,...
                'n_nearest', app.NneighboursEditField.Value, ...           % число ближайших соседей
                'max_dim', app.MaxdimensionsSpinner.Value, ...    % до какой размерности исследовать
                'step', app.StepEditField.Value, ...                 % с каким шагом
                'imp_hwidth_ns', app.PulsehwidthnsEditField.Value, ...   % продолжительность импульса по половинному уровню, нс
                'mse_noise', app.MSEmaxEditField.Value,...            % СКО шума
                'cv_method',app.CVtypeDropDown.Value, 'cv_param', str2double(app.FoldsDropDown.Value),...
                'classifier',app.ClassifiertypeDropDown.Value, 'layers', net_layers, ...
                'decision_mode', app.DecisionmodeDropDown.Value, 'use_gpu',use_gpu)
        end
        
        % запустить визуализацию отбора признаков
        function run_crossval_check_vis(app)
            
            dset_folder_name = app.CurrentdatasetEditField.Value;        % dataset
            t_select_v = [app.t_types{:,2}];                     % маска выбора объектов

            % Параметры алгоритма отбора
            reduction_method = app.ReductionalgorithmDropDown.Value;  % метод редукции
            
            n_metrics_fraction = app.MetricsfractionEditField.Value;  % доля метрик
            n_nearest = app.NneighboursEditField.Value;
            
            max_dim = app.MaxdimensionsSpinner.Value;    % до какой размерности исследовать
            step = app.StepEditField.Value;                 % с каким шагом
            dimensions_init = 0:step:max_dim;               % вектор исследуемых размерностей
            
            imp_hwidth_ns = app.PulsehwidthnsEditField.Value;   % продолжительность импульса по половинному уровню, нс
            mse_noise = app.MSEmaxEditField.Value;            % СКО шума
            t_bfuns = app.t_types(:,4);
            t_bfuns = t_bfuns(t_select_v); 
            external_input=1;
            cv_method = app.CVtypeDropDown.Value;
            crossval_check_vis
            
        end
        
        %TODO запустить визуализацию данных выбранных из списка
        function run_crossval_check_from_list(app)
            filelist = app.ResultslistListBox.Value;
            filelist = cellfun(@(fname) horzcat(app.dataset_directory, '\results\', fname), ...
                filelist, 'UniformOutput', false);
            Crossvalidation.crossval_list_vis(filelist,...
                'confusion', app.ShowconfusionmxCheckBox.Value,...
                'maps',app.ShowmapsCheckBox.Value)
        end
                
        
        % замена текста в датапоинте t-SNE
        function datatipTxt = tsne_datapoint_popup(app,empt,evt)
            target = get(evt,'Target');
            ind = get(evt,'DataIndex');
            group = getappdata(target,'group');
            groupname = getappdata(target,'groupname');
            %gind = getappdata(target,'gind');
            % Multiple groups
            % If group name not given, assign it its number
            asp_a = app.meta_data{group}(ind).asp_a;
            asp_b = app.meta_data{group}(ind).asp_b;
            asp = num2str([asp_a,asp_b],'(%02.0f,%02.0f)');
            
            %datatipTxt = {getString(message('stats:gscatter:Group',groupname))};
            %datatipTxt{end+1} = getString(message('stats:gscatter:Observation',asp));
            datatipTxt = {getString(message('stats:gscatter:Observation',[groupname, asp]))};
            
        end
        
        % Проверить необходимость дополнительного анализа
        function regen_vect = update_red_panel(app)
            app.populate_result_list();          % заполнить список результатов
            if ~app.manualselectionCheckBox.Value
                app.conditional_select();
            end
        end
        
        function populate_result_list(app)
            if app.filterCheckBox.Value
                mask =  [app.dataset_directory '\results\' app.searchField.Value '.mat'];
            else
                mask =  [app.dataset_directory '\results\*dim*.mat'];
            end
            dir_list = dir(mask);                             % формирование списка объектов из списка папок с ИХР
            app.ResultslistListBox.Items = {dir_list(1:end).name};      % объекты;
%             mask =  [app.dataset_directory '\results\*dim*.mat'];            
%             dir_list = dir(mask);                             % формирование списка объектов из списка папок с ИХР
%             if app.filterCheckBox.Value
%                 names = {};
%                 for i = 1:length(dir_list(1:end))
%                     if ~isempty(strfind(dir_list(i).name, app.searchField.Value))
%                         names{end+1} = dir_list(i).name;
%                     end
%                 end
%             else 
%                 names = {dir_list(1:end).name};
%             end
%             app.ResultslistListBox.Items = names;      % объекты;
        end
        
        function conditional_select(app)
            % выбрвать в списке результаты, которые соответсвуют начальным
            % условиям
            t_select_v = [app.t_types{:,2}];                % вектор выбранных преобразований
            t_types_l = app.t_types(logical(t_select_v),1); % идентификаторы преобразований
            t_bfuns = app.t_types(t_select_v,4);
            n_transforms = size(t_types_l,1);  % число преобразований
            
            % прочитать исходные
            imp_hwidth_ns = app.PulsehwidthnsEditField.Value;
            mse_noise = app.MSEmaxEditField.Value;
            cv_method = app.CVtypeDropDown.Value;
            reduction_method = app.ReductionalgorithmDropDown.Value;
            n_metrics_fraction = app.MetricsfractionEditField.Value;
            n_nearest = app.NneighboursEditField.Value;
            k_alien = app.kforrectificationEditField.Value;
            max_dim = app.MaxdimensionsSpinner.Value;    % до какой размерности исследовать
            step = app.StepEditField.Value;                 % с каким шагом
            
            switch reduction_method
                case {'nmin','integrnmin','integrnmax'},          red_param = num2str(n_metrics_fraction);
                case {'buryi', 'fisher','auhist','integr','buryisum','integr_min', 'sequent'},   red_param = '';
                case {'minalien','prat','wprat','test'},    red_param = num2str(n_nearest);
                case {'refmmin'},                    red_param = [num2str(k_alien) 'of' num2str(n_nearest)];
                otherwise,          error('Неизвестный метод редукции')
            end
            app.exp_id = ['t' num2str(imp_hwidth_ns,'%02.1f') ...
                '_mse' num2str(max(mse_noise)) ]; % обновить id эксперимента
            result_filename_const = ...
                [ cv_method ...             исходная выборка
                '_t' num2str(imp_hwidth_ns,'%02.1f') ...    t импульса
                '_mse' num2str(max(mse_noise)) ...          СКО шума
                '_' reduction_method num2str(red_param)...       метрик в штрафной зоне
                '_dim' num2str(step) '-' num2str(max_dim) ...         максимальная размерность
                ]; 
            missing_vect = zeros(n_transforms,1);     % вектор отсутствия результата
            selected_items = cell(n_transforms,1);    % выбранные результаты
            for i_t_type = 1:n_transforms
                t_type = t_types_l{i_t_type};      % идент. преобразования
                t_bfun = t_bfuns{i_t_type};
                mat_filename = [ result_filename_const '_' t_type '_' t_bfun '.mat'];
                if  ~any(strcmp(app.ResultslistListBox.Items,mat_filename))
                    missing_vect(i_t_type) = 1;
                    %warning(['Исследование не проведено: отсутсвует файл ' mat_filename])
                else
                    selected_items{i_t_type} = mat_filename; % добавить файл к выбраным
                end
                        
            end
            if any(missing_vect)
                app.ResultsavailableLamp.Color = 'r';
            else, app.ResultsavailableLamp.Color = 'g';
            end
            if any(~missing_vect)
            app.ResultslistListBox.Value = {selected_items{~missing_vect}}; end
        end
    
    end
        
    methods (Access = public)
        
        function tsne_grad(app)
            n_types = size(app.t_types, 1);
            object_markers = {'^','x','o','+','s','*'}; 
            for i_type = 1:n_types
                if app.t_types{i_type,2}                % если это преобразование задействовано
                    t_type = app.t_types{i_type, 1};    % извлечь название перобразования
                    % загрузка и подготовка данных
                    load([app.dataset_directory '/results/tsne(' app.exp_id '_' t_type  ').mat'], "tsne_data","e_exag","perplex");
                    tsne_scatter = vertcat(tsne_data.tsne_scatter);
                    % настройка цвета
                    asp_a= vertcat(tsne_data.asp_a);
                    max_asp_a = max(asp_a);
                    col = asp_a;     % цвет маркера
                    sz = 20;                % размер
                    % настройка маркера 
                    loc_objs = {tsne_data.name}.';              % массив ячеек с именами объектов
                    [object_names, ~, ci] = unique(loc_objs);   % собрать имена объектов
                    % здесь ci - номера объекта, соответствует i-му имени в массиве object_names
                    n_objects = length(object_names);   % число объектов
                    % графика
                    f_handle = figure('Name',t_type,'color', 'white','WindowStyle','docked');
                    hold on;
                    % постройка по объектам
                    for i_obj = 1:n_objects
                        obj_scatter = tsne_scatter(i_obj==ci,:);    % найти точки i-го объекта и упаковать их
                        obj_col = col(i_obj==ci,:);         
                        scatter(obj_scatter(:,1),obj_scatter(:,2),sz,obj_col,object_markers{i_obj});
                    end
                    colorbar('Ticks',0:5:max_asp_a,...
                        'TickLabels',num2str(0:5:max_asp_a,'%02d\n'));
                    title(['TSNE analysis (' replace(app.exp_id,'_',' ') ', ' t_type ...
                        '), P=' num2str(perplex) ', E=' num2str(e_exag)])
                    legend(object_names)
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpendatasetButton
        function OpendatasetButtonPushed(app, event)
             app.dataset_directory = uigetdir('./', 'Select project data');     % открыть папку с набором данных
             if app.dataset_directory~=0 
                app.load_app_state();
             end
             
        end

        % Button pushed function: OpenICRsButton
        function OpenICRsButtonPushed(app, event)
            app.src_icrs_directory = uigetdir('./', 'Select icr directory');    % интерфейс для выбора папки с ИХР
            if app.src_icrs_directory~=0
                app.open_icrs();
            end
                        
        end

        % Button pushed function: GenerateICRsbaseButton
        function GenerateICRsbaseButtonPushed(app, event)
            % проверить существует ли папка проекта 
            if exist(app.dataset_directory,'dir')~=7
                mkdir(app.dataset_directory);
                mkdir([app.dataset_directory '/' 'icrs']);
            end
            [icrs, meta] = app.get_all_icrs();  % загрузка ИХР
            
            fprintf('(%s) %s\n', datetime('now','Format','HH:mm:ss'), ['Загружены ИХР из ' app.src_icrs_directory])
            
            [~,~] = mkdir([app.dataset_directory '/' 'results']);   % создание папок с названиями extras, fvs, results
            [~,~] = mkdir([app.dataset_directory '/' 'fvs']);
            [~,~] = mkdir([app.dataset_directory '/' 'extras']);
            show_asp = app.asp_selection;
            if app.ShowICRSCheckBox.Value            
                f = figure('Name','ИХР', 'color', 'white','WindowStyle','docked');
                n_obj = length(app.ICRListBox.Value); 
                for i_obj = 1:n_obj
                    axes = subplot(1,n_obj,i_obj); 
                    RPTools.plot_rps(axes, icrs{i_obj}, meta{i_obj}, show_asp);
                    xlabel(axes,'отсчеты'); 
                    ylabel(axes, 'ИХР, отн.ед.')
                end
                savefig(f, [app.dataset_directory '/extras/' 'icrs.fig'])
            end
            app.save_app_state();
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            app.ChangeButton.Text = 'Save';
            app.CurrentdatasetEditField.Editable = true;
            
        end

        % Value changed function: CurrentdatasetEditField
        function CurrentdatasetEditFieldValueChanged(app, event)
            app.CurrentdatasetEditField.Editable = false;
            app.dataset_directory = ['./' app.CurrentdatasetEditField.Value];
            app.ChangeButton.Text = 'Change';
        end

        % Button pushed function: GenerateRPSButton
        function GenerateRPSButtonPushed(app, event)
            [icrs, meta] = app.get_all_icrs();
            rps = app.get_rps(icrs, meta);
            app.get_noise_rps(rps,meta);
            app.save_app_state();
        end

        % Button pushed function: ProcessDRPSButton
        function ProcessDRPSButtonPushed(app, event)
            [icrs, meta] = app.get_all_icrs();
            [rps] = app.get_rps(icrs, meta);
            [rps_n, meta_n] = app.get_noise_rps(rps, meta);
            app.get_drps(rps_n,meta_n);
            
            app.save_app_state();
        end

        % Button pushed function: GenerateFVSButton
        function GenerateFVSButtonPushed(app, event)
            app.get_fvs();          % сформировать вектора признаков
        end

        % Callback function: ACWTCheckBox, AFFTCheckBox, 
        % CVtypeDropDown, CWTCheckBox, FFTCheckBox, NoneCheckBox, 
        % PCACheckBox, ReductionalgorithmDropDown, TabGroup, 
        % WTCheckBox, savetocsvCheckBox, waveletacwtDropDown, 
        % waveletcwtDropDown, waveletwtDropDown
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if isequal(selectedTab,app.FeaturesTab)
                app.update_fvs_panel();
                app.update_red_panel();
            end
        end

        % Value changed function: savetomatCheckBox
        function savetomatCheckBoxValueChanged(app, event)
            app.update_fvs_panel();
        end

        % Button pushed function: ProcessTSNEButton
        function ProcessTSNEButtonPushed(app, event)
            fprintf('(%s)==== %s ====\n', datetime('now','Format', 'HH:mm:ss'), 'Анализ разделимости')
            [fvs_cell, meta] = app.get_fvs();   % получить вектора признаков 
            app.tsne_cell(fvs_cell,meta);       % выполнить tSNE-анализ
            fprintf('(%s)==== %s ====\n', datetime('now','Format', 'HH:mm:ss'), 'Анализ разделимости завершен')
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            % редукция пространств признаков
            fprintf('(%s)==== %s ====\n', datetime('now','Format','HH:mm:ss'), 'Отбор признаков и анализ качества распознавания')
            app.save_app_state();
            app.get_fvs();
            app.run_crossval_script();
            fprintf('(%s)==== %s ====\n', datetime('now','Format','HH:mm:ss'), 'Отбор признаков и анализ качества распознавания завершен')
            app.update_red_panel()
            if app.ShowgraphicsCheckBox_5.Value,    app.run_crossval_check_vis();   end
        end

        % Button pushed function: GraphicsButton_2
        function GraphicsButton_2Pushed(app, event)
            % вывести результат анализа
            if app.manualselectionCheckBox.Value
                app.run_crossval_check_from_list();
            else                                   
                app.CheckResButton();
                if any(app.ResultsavailableLamp.Color~=[1 0 0]), app.run_crossval_check_vis(); end
            end
        end

        % Button pushed function: GradientScatter
        function GradientScatterButtonPushed(app, event)
            app.tsne_grad();
        end

        % Value changed function: MeasureinFSCheckBox
        function MeasureinFSCheckBoxValueChanged(app, event)
            value = app.MeasureinFSCheckBox.Value;
            
        end

        % Value changed function: MeasuretechniqueDropDown
        function MeasuretechniqueDropDownValueChanged(app, event)
            value = app.MeasuretechniqueDropDown.Value;
            switch value
                case {'mean' 'prat'}
                    app.NnearestEditField.Enable=true;
                    app.KAlienneighboursEditField.Enable=false;
                    app.KAlienneighboursEditFieldLabel.Enable=false;
                case 'thresh'
                    app.NnearestEditField.Enable=true;
                    app.KAlienneighboursEditField.Enable=true;
                    app.KAlienneighboursEditFieldLabel.Enable=true;
                otherwise 
                    error('Неизвестный метод оценки')
            end
        end

        % Value changed function: MeasureinSNECheckBox
        function MeasureinSNECheckBoxValueChanged(app, event)
            if app.MeasureinSNECheckBox.Value
                app.ApplySNECheckBox.Value=true;
            end
        end

        % Button pushed function: ClearDirButton
        function ClearDirButtonPushed(app, event)
            fvs_folder = [ app.dataset_directory '/fvs/'];
            file_pattern = fullfile(fvs_folder, '*.mat'); % Change to whatever pattern you need.
            fvs_files = dir(file_pattern);
            for k = 1 : length(fvs_files)
                base_fname = fvs_files(k).name;
                full_fname = fullfile(fvs_folder, base_fname);
                delete(full_fname);
                fprintf(1, 'Удален %s\n', base_fname);
            end
            app.update_fvs_panel();
        end

        % Value changed function: manualselectionCheckBox
        function manualselectionCheckBoxValueChanged(app, event)
            value = app.manualselectionCheckBox.Value;
            app.ResultslistListBox.Enable = value;          % включить выключить список
        end

        % Selection changed function: MSEvaluepresetButtonGroup
        function MSEvaluepresetButtonGroupSelectionChanged(app, event)
            if app.SinglevalueButton.Value
                app.MSEminEditField.Enable=false;
            else
                app.MSEminEditField.Enable=true;
            end
        end

        % Value changed function: MSEmaxEditField
        function MSEmaxEditFieldValueChanged(app, event)
            value = app.MSEmaxEditField.Value;
            app.max_dBLabel.Text = app.get_snr_db(value);
        end

        % Value changed function: MSEminEditField
        function MSEminEditFieldValueChanged(app, event)
            value = app.MSEminEditField.Value;
            app.min_dBLabel.Text = app.get_snr_db(value);
        end

        % Button pushed function: CheckResButton
        function CheckResButtonPushed(app, event)
            app.update_fvs_panel();
            app.update_red_panel();
        end

        % Button pushed function: CurvesfordimsButton
        function CurvesfordimsButtonPushed(app, event)
            dims = [app.StepEditField.Value:app.MaxdimensionsSpinner.Value];
            filelist = app.ResultslistListBox.Value;
            filelist = cellfun(@(fname) horzcat(app.dataset_directory, '\results\', fname), ...
                filelist, 'UniformOutput', false);
            Crossvalidation.compare_nmins(filelist, 'dimensions', dims, 'maps',app.ShowmapsCheckBox.Value)
        end

        % Button pushed function: TestclassifierButton
        function TestclassifierButtonPushed(app, event)
            % Принцип работы: считываются параметры ДП, выполняется загрузка карт признаков, загрузка классификатора
            % (или) весов классификатора
            % 
            % Оценка качества
            fprintf('(%s)==== %s ====\n', datetime('now','Format','HH:mm:ss'), 'Отбор признаков и анализ качества распознавания')
            app.save_app_state();
            app.get_fvs();
            fprintf('(%s)==== %s ====\n', datetime('now','Format','HH:mm:ss'), 'Отбор признаков и анализ качества распознавания завершен')
            app.update_red_panel()
            if app.ShowgraphicsCheckBox_5.Value,    app.run_crossval_check_vis();   end
            
            if app.readfromUIButton.Value
                net_layers = [app.L1Spinner.Value, app.L2Spinner.Value, app.L3Spinner.Value, app.L4Spinner.Value];  % слои сети
                net_layers(net_layers==0)=[];
            else
                error('Function is not yet available!')
            end
            if app.signatureButton.Value
                test_files = "signature";
            elseif app.filesButton.Value
                test_files = "open_files";
            else
                error('This test set mode is unavailable!')
            end
            if app.UseGPUCheckBox.Value, use_gpu='yes'; else, use_gpu='no'; end
            Crossvalidation.assess_fspaces(...
                'dset_folder_name',app.CurrentdatasetEditField.Value, ...       % dataset
                't_select_v', [app.t_types{:,2}], ...                            % маска выбора объектов
                't_bfuns', app.t_types(:,4), ...
                'reduction_method', app.ReductionalgorithmDropDown.Value, ...  % метод редукции
                'heuristic', app.HeuristicDropDown.Value,...
                'n_metrics_fraction', app.MetricsfractionEditField.Value, ...  % доля метрик
                'k_alien', app.kforrectificationEditField.Value,...
                'n_nearest', app.NneighboursEditField.Value, ...           % число ближайших соседей
                'max_dim', app.MaxdimensionsSpinner.Value, ...    % до какой размерности исследовать
                'step', app.StepEditField.Value, ...                 % с каким шагом
                'imp_hwidth_ns', app.PulsehwidthnsEditField.Value, ...   % продолжительность импульса по половинному уровню, нс
                'mse_noise', app.MSEmaxEditField.Value,...            % СКО шума
                'cv_method',app.CVtypeDropDown.Value, 'cv_param', str2double(app.FoldsDropDown.Value),...
                'classifier',app.ClassifiertypeDropDown.Value, 'layers', net_layers, ...
                'decision_mode', app.DecisionmodeDropDown.Value, 'use_gpu',use_gpu, ...
                'test_files', test_files)
            
            
        end

        % Value changed function: filterCheckBox, searchField
        function searchFieldValueChanged(app, event)
            value = app.searchField.Value;
            app.populate_result_list()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LLISTab = uitab(app.TabGroup);
            app.LLISTab.Title = 'LLIS';
            app.RPSgenerationPanel = uipanel(app.LLISTab);
            app.RPSgenerationPanel.Title = 'RPS generation';
            app.GridLayout5 = uigridlayout(app.RPSgenerationPanel);
            app.PulsehwidthnsEditFieldLabel = uilabel(app.GridLayout5);
            app.PulsehwidthnsEditFieldLabel.Text = 'Pulse h/width, ns';
            app.PulsehwidthnsEditField = uieditfield(app.GridLayout5, 'numeric');
            app.PulsehwidthnsEditField.Limits = [0.5 30];
            app.PulsehwidthnsEditField.Value = 4;
            app.PrenoisenormDropDownLabel = uilabel(app.GridLayout5);
            app.PrenoisenormDropDownLabel.Text = 'Pre-noise norm';
            app.PrenoisenormDropDown = uidropdown(app.GridLayout5);
            app.PrenoisenormDropDown.Items = {'none', 'mean', 'max_peak', 'energy', 'mod'};
            app.PrenoisenormDropDown.Value = 'mod';
            app.NoisesamplesEditFieldLabel = uilabel(app.GridLayout5);
            app.NoisesamplesEditFieldLabel.Text = 'Noise samples';
            app.NoisesamplesEditField = uieditfield(app.GridLayout5, 'numeric');
            app.NoisesamplesEditField.Limits = [1 256];
            app.NoisesamplesEditField.ValueDisplayFormat = '%.0f';
            app.NoisesamplesEditField.Value = 1;
            app.WindowmultiplierEditFieldLabel = uilabel(app.GridLayout5);
            app.WindowmultiplierEditFieldLabel.Text = 'Window multiplier';
            app.WindowmultiplierEditField = uieditfield(app.GridLayout5, 'numeric');
            app.WindowmultiplierEditField.Limits = [1 5];
            app.WindowmultiplierEditField.Value = 1;
            app.ShowRPSCheckBox = uicheckbox(app.GridLayout5);
            app.ShowRPSCheckBox.Text = 'Show graphics';
            app.GenerateRPSButton = uibutton(app.GridLayout5, 'push');
            app.GenerateRPSButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRPSButtonPushed, true);
            app.GenerateRPSButton.Text = 'Generate RPS';
            app.MSEvaluepresetButtonGroup = uibuttongroup(app.GridLayout5);
            app.MSEvaluepresetButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MSEvaluepresetButtonGroupSelectionChanged, true);
            app.MSEvaluepresetButtonGroup.Title = 'MSE value preset';
            app.SinglevalueButton = uiradiobutton(app.MSEvaluepresetButtonGroup);
            app.SinglevalueButton.Text = 'Single value:';
            app.SinglevalueButton.Value = true;
            app.RangeButton = uiradiobutton(app.MSEvaluepresetButtonGroup);
            app.RangeButton.Text = 'Range';
            app.MSEminEditField = uieditfield(app.MSEvaluepresetButtonGroup, 'numeric');
            app.MSEminEditField.ValueChangedFcn = createCallbackFcn(app, @MSEminEditFieldValueChanged, true);
            app.MSEminEditField.Enable = 'off';
            app.MSEmaxEditField = uieditfield(app.MSEvaluepresetButtonGroup, 'numeric');
            app.MSEmaxEditField.Limits = [0 20];
            app.MSEmaxEditField.ValueChangedFcn = createCallbackFcn(app, @MSEmaxEditFieldValueChanged, true);
            app.max_dBLabel = uilabel(app.MSEvaluepresetButtonGroup);
            app.max_dBLabel.Text = '00 dB';
            app.min_dBLabel = uilabel(app.MSEvaluepresetButtonGroup);
            app.min_dBLabel.Text = '00 dB';
            app.LLISrecievermodelPanel = uipanel(app.LLISTab);
            app.LLISrecievermodelPanel.Title = 'LLIS reciever model';
            app.GridLayout4 = uigridlayout(app.LLISrecievermodelPanel);
            app.FilterbandmultEditFieldLabel = uilabel(app.GridLayout4);
            app.FilterbandmultEditFieldLabel.Tooltip = {''};
            app.FilterbandmultEditFieldLabel.Text = 'Filter band mult';
            app.FilterbandmultEditField = uieditfield(app.GridLayout4, 'numeric');
            app.FilterbandmultEditField.Limits = [0.01 10];
            app.FilterbandmultEditField.ValueDisplayFormat = '%1.1f';
            app.FilterbandmultEditField.Tooltip = {'Полоса фильтра относительно полосы ЗИ по трем sigma'};
            app.FilterbandmultEditField.Value = 1.1;
            app.ADCbitsEditFieldLabel = uilabel(app.GridLayout4);
            app.ADCbitsEditFieldLabel.Text = 'ADC bits';
            app.ADCbitsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ADCbitsEditField.Limits = [4 32];
            app.ADCbitsEditField.ValueDisplayFormat = '%.0f';
            app.ADCbitsEditField.Value = 16;
            app.en_quantize = uicheckbox(app.GridLayout4);
            app.en_quantize.Text = '';
            app.en_quantize.Value = true;
            app.en_sampling = uicheckbox(app.GridLayout4);
            app.en_sampling.Text = '';
            app.en_sampling.Value = true;
            app.FsamplingEditFieldLabel = uilabel(app.GridLayout4);
            app.FsamplingEditFieldLabel.Text = 'F sampling';
            app.FsamplingEditField = uieditfield(app.GridLayout4, 'numeric');
            app.FsamplingEditField.Limits = [0.1 10];
            app.FsamplingEditField.ValueDisplayFormat = '%1.1f';
            app.FsamplingEditField.Tooltip = {'Полоса АЦП относительно полосы фильтра'};
            app.FsamplingEditField.Value = 2;
            app.PostADCnormDropDownLabel = uilabel(app.GridLayout4);
            app.PostADCnormDropDownLabel.Text = 'Post-ADC norm';
            app.PostADCnormDropDown = uidropdown(app.GridLayout4);
            app.PostADCnormDropDown.Items = {'none', 'mean', 'max_peak', 'energy', 'mod'};
            app.PostADCnormDropDown.Value = 'mod';
            app.UpsamlefactorEditFieldLabel = uilabel(app.GridLayout4);
            app.UpsamlefactorEditFieldLabel.Text = 'Upsamle factor';
            app.UpsamlefactorEditField = uieditfield(app.GridLayout4, 'numeric');
            app.UpsamlefactorEditField.Limits = [0 10];
            app.UpsamlefactorEditField.ValueDisplayFormat = '%.0f';
            app.UpsamlefactorEditField.Value = 3;
            app.ShowDRPSCheckBox = uicheckbox(app.GridLayout4);
            app.ShowDRPSCheckBox.Text = 'Show graphics';
            app.TimesnapDropDownLabel = uilabel(app.GridLayout4);
            app.TimesnapDropDownLabel.Text = 'Time snap';
            app.TimesnapDropDown = uidropdown(app.GridLayout4);
            app.TimesnapDropDown.Items = {'none', 'energy', 'max_peak'};
            app.TimesnapDropDown.Tooltip = {'Метод выбора характерной точки:'; '(none) первый отсчёт'; '(energy) - центр тяжести ДП'; '(max_peak) - пиковый отсчёт'};
            app.TimesnapDropDown.Value = 'energy';
            app.OffsetcountsEditFieldLabel = uilabel(app.GridLayout4);
            app.OffsetcountsEditFieldLabel.Text = 'Offset (counts)';
            app.OffsetcountsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.OffsetcountsEditField.Limits = [0 1024];
            app.OffsetcountsEditField.Value = 10;
            app.UpsamletechniqueDropDownLabel = uilabel(app.GridLayout4);
            app.UpsamletechniqueDropDownLabel.Text = 'Upsamle technique';
            app.UpsamletechniqueDropDown = uidropdown(app.GridLayout4);
            app.UpsamletechniqueDropDown.Items = {'none', 'poly', 'freq', 'Lagrange', 'spline', ''};
            app.UpsamletechniqueDropDown.Value = 'freq';
            app.ProcessDRPSButton = uibutton(app.GridLayout4, 'push');
            app.ProcessDRPSButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessDRPSButtonPushed, true);
            app.ProcessDRPSButton.Text = 'Process DRPS';
            app.JittersnapEditFieldLabel = uilabel(app.GridLayout4);
            app.JittersnapEditFieldLabel.Text = 'Jitter snap';
            app.JittersnapEditField = uieditfield(app.GridLayout4, 'numeric');
            app.JittersnapEditField.Tooltip = {'Моделирование неточности поиска характерной точки:'; 'задаётся ширина окна в котором сдвиг распределен равномерно.'};
            app.FeaturesTab = uitab(app.TabGroup);
            app.FeaturesTab.Title = 'Features';
            app.GridLayout8 = uigridlayout(app.FeaturesTab);
            app.TSNEPanel = uipanel(app.GridLayout8);
            app.TSNEPanel.Title = 'TSNE';
            app.GridLayout3 = uigridlayout(app.TSNEPanel);
            app.DistanceLabel = uilabel(app.GridLayout3);
            app.DistanceLabel.Text = 'Distance';
            app.DistanceDropDown = uidropdown(app.GridLayout3);
            app.DistanceDropDown.Items = {'Euclidean', 'Mahalanobis', '1-cos', '1-corr'};
            app.DistanceDropDown.ItemsData = {'euclidean', 'mahalanobis', 'cosine', 'correlation'};
            app.DistanceDropDown.Value = 'euclidean';
            app.PerplexityLabel = uilabel(app.GridLayout3);
            app.PerplexityLabel.Text = {'Perplexity'; ''};
            app.PerplexityEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PerplexityEditField.Limits = [1 200];
            app.PerplexityEditField.ValueDisplayFormat = '%.0f';
            app.PerplexityEditField.Value = 30;
            app.EarlyexagerationEditFieldLabel = uilabel(app.GridLayout3);
            app.EarlyexagerationEditFieldLabel.Text = 'Early exageration';
            app.EarlyexagerationEditField = uieditfield(app.GridLayout3, 'numeric');
            app.EarlyexagerationEditField.Limits = [1 200];
            app.EarlyexagerationEditField.ValueDisplayFormat = '%.0f';
            app.EarlyexagerationEditField.Value = 5;
            app.LearningrateEditFieldLabel = uilabel(app.GridLayout3);
            app.LearningrateEditFieldLabel.Text = 'Learning rate';
            app.LearningrateEditField = uieditfield(app.GridLayout3, 'numeric');
            app.LearningrateEditField.Limits = [1 2000];
            app.LearningrateEditField.ValueDisplayFormat = '%.0f';
            app.LearningrateEditField.Value = 500;
            app.ApplySNECheckBox = uicheckbox(app.GridLayout3);
            app.ApplySNECheckBox.Text = 'Apply SNE';
            app.ProcessTSNEButton = uibutton(app.GridLayout3, 'push');
            app.ProcessTSNEButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessTSNEButtonPushed, true);
            app.ProcessTSNEButton.Text = 'Process data';
            app.GradientScatter = uibutton(app.GridLayout3, 'push');
            app.GradientScatter.ButtonPushedFcn = createCallbackFcn(app, @GradientScatterButtonPushed, true);
            app.GradientScatter.Text = 'Marked apects';
            app.MeasureinSNECheckBox = uicheckbox(app.GridLayout3);
            app.MeasureinSNECheckBox.ValueChangedFcn = createCallbackFcn(app, @MeasureinSNECheckBoxValueChanged, true);
            app.MeasureinSNECheckBox.Text = 'Measure in SNE';
            app.MeasureinFSCheckBox = uicheckbox(app.GridLayout3);
            app.MeasureinFSCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeasureinFSCheckBoxValueChanged, true);
            app.MeasureinFSCheckBox.Text = 'Measure in FS';
            app.MeasureinFSCheckBox.Value = true;
            app.MeasuretechniqueDropDownLabel = uilabel(app.GridLayout3);
            app.MeasuretechniqueDropDownLabel.Text = 'Measure technique';
            app.MeasuretechniqueDropDown = uidropdown(app.GridLayout3);
            app.MeasuretechniqueDropDown.Items = {'mean', 'thresh', 'prat'};
            app.MeasuretechniqueDropDown.ValueChangedFcn = createCallbackFcn(app, @MeasuretechniqueDropDownValueChanged, true);
            app.MeasuretechniqueDropDown.Value = 'prat';
            app.NnearestEditFieldLabel = uilabel(app.GridLayout3);
            app.NnearestEditFieldLabel.Text = 'N nearest';
            app.NnearestEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NnearestEditField.Limits = [1 120];
            app.NnearestEditField.ValueDisplayFormat = '%.0f';
            app.NnearestEditField.Tooltip = {'K соседей'; ' используемых при количественной оценке разделимосте'};
            app.NnearestEditField.Value = 10;
            app.KAlienneighboursEditFieldLabel = uilabel(app.GridLayout3);
            app.KAlienneighboursEditFieldLabel.Enable = 'off';
            app.KAlienneighboursEditFieldLabel.Text = 'K Alien neighbours';
            app.KAlienneighboursEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KAlienneighboursEditField.Limits = [0 120];
            app.KAlienneighboursEditField.Enable = 'off';
            app.KAlienneighboursEditField.Tooltip = {'Используется при пороговой оценке: если "чужих" больше этого значения'; ' то вектор в чужой зоне'};
            app.KAlienneighboursEditField.Value = 6;
            app.FeatureselectionPanel = uipanel(app.GridLayout8);
            app.FeatureselectionPanel.Title = 'Feature selection';
            app.GridLayout6 = uigridlayout(app.FeatureselectionPanel);
            app.StepLabel = uilabel(app.GridLayout6);
            app.StepLabel.Text = 'Step:';
            app.StepEditField = uieditfield(app.GridLayout6, 'numeric');
            app.StepEditField.Limits = [0 20];
            app.StepEditField.RoundFractionalValues = 'on';
            app.StepEditField.ValueDisplayFormat = '%.0f';
            app.StepEditField.Value = 1;
            app.CVtypeDropDownLabel = uilabel(app.GridLayout6);
            app.CVtypeDropDownLabel.Text = 'CV type:';
            app.CVtypeDropDown = uidropdown(app.GridLayout6);
            app.CVtypeDropDown.Items = {'kfold', 'holdout'};
            app.CVtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CVtypeDropDown.Value = 'kfold';
            app.FoldsLabel = uilabel(app.GridLayout6);
            app.FoldsLabel.Text = 'Folds:';
            app.FoldsDropDown = uidropdown(app.GridLayout6);
            app.FoldsDropDown.Items = {'2', '3', '4', '5'};
            app.FoldsDropDown.Value = '4';
            app.MaxdimensionsSpinnerLabel = uilabel(app.GridLayout6);
            app.MaxdimensionsSpinnerLabel.Text = 'Max dimensions';
            app.MaxdimensionsSpinner = uispinner(app.GridLayout6);
            app.MaxdimensionsSpinner.ValueDisplayFormat = '%.0f';
            app.MaxdimensionsSpinner.Value = 10;
            app.ReductionalgorithmLabel = uilabel(app.GridLayout6);
            app.ReductionalgorithmLabel.Text = {'Reduction algorithm'; ''};
            app.ReductionalgorithmDropDown = uidropdown(app.GridLayout6);
            app.ReductionalgorithmDropDown.Items = {'Maxmin metric', 'Buryi 98', 'Metrics sum', 'Metrics lower sum', 'Metrics upper sum', 'Threshold metric', 'Min Alien', 'P ratio', 'Weighted P ratio', 'Fisher', 'Rect Maxmin', 'Area under hist', 'Sum mins', 'Sequential', 'Test alg'};
            app.ReductionalgorithmDropDown.ItemsData = {'buryi', 'buryisum', 'integr', 'integrnmin', 'integrnmax', 'nmin', 'minalien', 'prat', 'wprat', 'fisher', 'refmmin', 'auhist', 'integr_min', 'sequent', 'test'};
            app.ReductionalgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ReductionalgorithmDropDown.Value = 'refmmin';
            app.MetricsfractionLabel = uilabel(app.GridLayout6);
            app.MetricsfractionLabel.Text = 'Metrics fraction:';
            app.MetricsfractionEditField = uieditfield(app.GridLayout6, 'numeric');
            app.MetricsfractionEditField.Value = 0.02;
            app.NneighboursEditFieldLabel = uilabel(app.GridLayout6);
            app.NneighboursEditFieldLabel.Text = 'N neighbours';
            app.NneighboursEditField = uieditfield(app.GridLayout6, 'numeric');
            app.NneighboursEditField.Limits = [1 100];
            app.NneighboursEditField.ValueDisplayFormat = '%.0f';
            app.NneighboursEditField.Value = 5;
            app.ClassifiertypeDropDownLabel = uilabel(app.GridLayout6);
            app.ClassifiertypeDropDownLabel.Text = 'Classifier type';
            app.ClassifiertypeDropDown = uidropdown(app.GridLayout6);
            app.ClassifiertypeDropDown.Items = {'Neural regressor', 'Neural classifier'};
            app.ClassifiertypeDropDown.ItemsData = {'mlp', 'pnet'};
            app.ClassifiertypeDropDown.Tooltip = {'Тип алгоритма машинного обучения. Для персептрона оценивается СКО выхода. Классификатор выдает оценку правдоподобия'; ' а качество его работы оценивается по перекрестной энтропии'};
            app.ClassifiertypeDropDown.Value = 'mlp';
            app.UseGPUCheckBox = uicheckbox(app.GridLayout6);
            app.UseGPUCheckBox.Tooltip = {'Use gpu for training'; 'WARNING! Might work unstable'};
            app.UseGPUCheckBox.Text = 'Use GPU';
            app.L1SpinnerLabel = uilabel(app.GridLayout6);
            app.L1SpinnerLabel.Text = 'L 1';
            app.L1Spinner = uispinner(app.GridLayout6);
            app.L1Spinner.ValueDisplayFormat = '%.0f';
            app.L1Spinner.Value = 16;
            app.L2SpinnerLabel = uilabel(app.GridLayout6);
            app.L2SpinnerLabel.Text = 'L 2';
            app.L2Spinner = uispinner(app.GridLayout6);
            app.L2Spinner.ValueDisplayFormat = '%.0f';
            app.L2Spinner.Value = 8;
            app.L3SpinnerLabel = uilabel(app.GridLayout6);
            app.L3SpinnerLabel.Text = 'L 3';
            app.L3Spinner = uispinner(app.GridLayout6);
            app.L3Spinner.ValueDisplayFormat = '%.0f';
            app.SavesubsetsCheckBox = uicheckbox(app.GridLayout6);
            app.SavesubsetsCheckBox.Enable = 'off';
            app.SavesubsetsCheckBox.Text = 'Save subsets';
            app.ShowgraphicsCheckBox_5 = uicheckbox(app.GridLayout6);
            app.ShowgraphicsCheckBox_5.Text = 'Show graphics';
            app.AnalyzeButton = uibutton(app.GridLayout6, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.manualselectionCheckBox = uicheckbox(app.GridLayout6);
            app.manualselectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @manualselectionCheckBoxValueChanged, true);
            app.manualselectionCheckBox.Text = 'manual selection';
            app.ResultslistListBoxLabel = uilabel(app.GridLayout6);
            app.ResultslistListBoxLabel.Enable = 'off';
            app.ResultslistListBoxLabel.Text = 'Results list';
            app.ResultslistListBox = uilistbox(app.GridLayout6);
            app.ResultslistListBox.Items = {};
            app.ResultslistListBox.Multiselect = 'on';
            app.ResultslistListBox.Enable = 'off';
            app.ResultslistListBox.Value = {};
            app.ResultsavailableLabel = uilabel(app.GridLayout6);
            app.ResultsavailableLabel.Text = 'Results available';
            app.ResultsavailableLamp = uilamp(app.GridLayout6);
            app.GraphicsButton_2 = uibutton(app.GridLayout6, 'push');
            app.GraphicsButton_2.ButtonPushedFcn = createCallbackFcn(app, @GraphicsButton_2Pushed, true);
            app.GraphicsButton_2.Text = 'Graphics';
            app.L4Label = uilabel(app.GridLayout6);
            app.L4Label.Text = 'L 4';
            app.L4Spinner = uispinner(app.GridLayout6);
            app.L4Spinner.ValueDisplayFormat = '%.0f';
            app.ShowconfusionmxCheckBox = uicheckbox(app.GridLayout6);
            app.ShowconfusionmxCheckBox.Text = 'Show confusion mx';
            app.ShowmapsCheckBox = uicheckbox(app.GridLayout6);
            app.ShowmapsCheckBox.Text = 'Show maps';
            app.ShowmapsCheckBox.Value = true;
            app.CheckResButton = uibutton(app.GridLayout6, 'push');
            app.CheckResButton.ButtonPushedFcn = createCallbackFcn(app, @CheckResButtonPushed, true);
            app.CheckResButton.Text = 'Check';
            app.CurvesfordimsButton = uibutton(app.GridLayout6, 'push');
            app.CurvesfordimsButton.ButtonPushedFcn = createCallbackFcn(app, @CurvesfordimsButtonPushed, true);
            app.CurvesfordimsButton.Text = 'Curves for dims';
            app.kforrectificationEditFieldLabel = uilabel(app.GridLayout6);
            app.kforrectificationEditFieldLabel.Text = 'k for rectification';
            app.kforrectificationEditField = uieditfield(app.GridLayout6, 'numeric');
            app.kforrectificationEditField.Limits = [1 100];
            app.kforrectificationEditField.ValueDisplayFormat = '%.0f';
            app.kforrectificationEditField.Value = 1;
            app.DecisionmodeDropDownLabel = uilabel(app.GridLayout6);
            app.DecisionmodeDropDownLabel.Text = 'Decision mode';
            app.DecisionmodeDropDown = uidropdown(app.GridLayout6);
            app.DecisionmodeDropDown.Items = {'Max', 'Metric'};
            app.DecisionmodeDropDown.ItemsData = {'max', 'metric'};
            app.DecisionmodeDropDown.Value = 'max';
            app.HeuristicDropDownLabel = uilabel(app.GridLayout6);
            app.HeuristicDropDownLabel.Text = 'Heuristic';
            app.HeuristicDropDown = uidropdown(app.GridLayout6);
            app.HeuristicDropDown.Items = {'Add-Del', 'Gray coded'};
            app.HeuristicDropDown.ItemsData = {'adddel', 'gray', ''};
            app.HeuristicDropDown.Tooltip = {'Method of scanning feature spaces'};
            app.HeuristicDropDown.Value = 'adddel';
            app.SaveclassifiersCheckBox = uicheckbox(app.GridLayout6);
            app.SaveclassifiersCheckBox.Enable = 'off';
            app.SaveclassifiersCheckBox.Text = 'Save classifiers';
            app.EditFieldLabel = uilabel(app.GridLayout6);
            app.EditFieldLabel.Text = 'Edit Field';
            app.filterCheckBox = uicheckbox(app.GridLayout6);
            app.filterCheckBox.ValueChangedFcn = createCallbackFcn(app, @searchFieldValueChanged, true);
            app.filterCheckBox.Text = 'filter';
            app.searchField = uieditfield(app.GridLayout6, 'text');
            app.searchField.ValueChangedFcn = createCallbackFcn(app, @searchFieldValueChanged, true);
            app.TransformsPanel = uipanel(app.GridLayout8);
            app.TransformsPanel.Title = 'Transforms';
            app.GridLayout2 = uigridlayout(app.TransformsPanel);
            app.NoneCheckBox = uicheckbox(app.GridLayout2);
            app.NoneCheckBox.ValueChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.NoneCheckBox.Text = 'None';
            app.NoneCheckBox.Value = true;
            app.LampN = uilamp(app.GridLayout2);
            app.FFTCheckBox = uicheckbox(app.GridLayout2);
            app.FFTCheckBox.ValueChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.FFTCheckBox.Text = 'FFT';
            app.AFFTCheckBox = uicheckbox(app.GridLayout2);
            app.AFFTCheckBox.ValueChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.AFFTCheckBox.Text = 'AFFT';
            app.CWTCheckBox = uicheckbox(app.GridLayout2);
            app.CWTCheckBox.ValueChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CWTCheckBox.Text = 'CWT';
            app.ACWTCheckBox = uicheckbox(app.GridLayout2);
            app.ACWTCheckBox.ValueChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ACWTCheckBox.Text = 'ACWT';
            app.WTCheckBox = uicheckbox(app.GridLayout2);
            app.WTCheckBox.ValueChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.WTCheckBox.Text = 'WT';
            app.PCACheckBox = uicheckbox(app.GridLayout2);
            app.PCACheckBox.ValueChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.PCACheckBox.Text = 'PCA';
            app.waveLabel = uilabel(app.GridLayout2);
            app.waveLabel.Text = 'wave';
            app.waveletcwtDropDown = uidropdown(app.GridLayout2);
            app.waveletcwtDropDown.Items = {'dtf1', 'dtf2', 'dtf3'};
            app.waveletcwtDropDown.ValueChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.waveletcwtDropDown.Value = 'dtf2';
            app.waveLabel_2 = uilabel(app.GridLayout2);
            app.waveLabel_2.Text = 'wave';
            app.waveletacwtDropDown = uidropdown(app.GridLayout2);
            app.waveletacwtDropDown.Items = {'dtf1', 'dtf2', 'dtf3'};
            app.waveletacwtDropDown.ValueChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.waveletacwtDropDown.Value = 'dtf2';
            app.waveLabel_3 = uilabel(app.GridLayout2);
            app.waveLabel_3.Text = 'wave';
            app.waveletwtDropDown = uidropdown(app.GridLayout2);
            app.waveletwtDropDown.Items = {'sym1', 'sym2', 'sym3', 'sym4', 'sym5'};
            app.waveletwtDropDown.ValueChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.waveletwtDropDown.Value = 'sym2';
            app.LampFFT = uilamp(app.GridLayout2);
            app.LampAFFT = uilamp(app.GridLayout2);
            app.LampCWT = uilamp(app.GridLayout2);
            app.LampACWT = uilamp(app.GridLayout2);
            app.LampWT = uilamp(app.GridLayout2);
            app.LampPCA = uilamp(app.GridLayout2);
            app.GenerateFVSButton = uibutton(app.GridLayout2, 'push');
            app.GenerateFVSButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFVSButtonPushed, true);
            app.GenerateFVSButton.Text = 'Generate FVS';
            app.ClearDirButton = uibutton(app.GridLayout2, 'push');
            app.ClearDirButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDirButtonPushed, true);
            app.ClearDirButton.Tooltip = {'Удалить все наборы векторов признаков из базы'};
            app.ClearDirButton.Text = 'Clear Dir';
            app.savetomatCheckBox = uicheckbox(app.GridLayout2);
            app.savetomatCheckBox.ValueChangedFcn = createCallbackFcn(app, @savetomatCheckBoxValueChanged, true);
            app.savetomatCheckBox.Text = 'save to .mat';
            app.savetomatCheckBox.Value = true;
            app.savetocsvCheckBox = uicheckbox(app.GridLayout2);
            app.savetocsvCheckBox.ValueChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.savetocsvCheckBox.Text = 'save to .csv';
            app.autoregenfvs = uicheckbox(app.GridLayout2);
            app.autoregenfvs.Text = 'auto regen';
            app.DCACheckBox = uicheckbox(app.GridLayout2);
            app.DCACheckBox.Text = 'DCA';
            app.LampDCA = uilamp(app.GridLayout2);
            app.traindcaCheckBox = uicheckbox(app.GridLayout2);
            app.traindcaCheckBox.Text = 'train';
            app.traindcaCheckBox.Value = true;
            app.trainpcaCheckBox = uicheckbox(app.GridLayout2);
            app.trainpcaCheckBox.Text = 'train';
            app.trainpcaCheckBox.Value = true;
            app.ClassificationTab = uitab(app.TabGroup);
            app.ClassificationTab.Title = 'Classification';
            app.TestclassifierButton = uibutton(app.ClassificationTab, 'push');
            app.TestclassifierButton.ButtonPushedFcn = createCallbackFcn(app, @TestclassifierButtonPushed, true);
            app.TestclassifierButton.Text = 'Test classifier';
            app.TestsetselectionButtonGroup = uibuttongroup(app.ClassificationTab);
            app.TestsetselectionButtonGroup.Title = 'Test set selection';
            app.signatureButton = uiradiobutton(app.TestsetselectionButtonGroup);
            app.signatureButton.Tooltip = {'Find datasets with specified LLS parameters'; ' but different noise levels'};
            app.signatureButton.Text = 'signature';
            app.signatureButton.Value = true;
            app.filesButton = uiradiobutton(app.TestsetselectionButtonGroup);
            app.filesButton.Text = 'files';
            app.userinterfaceButton = uiradiobutton(app.TestsetselectionButtonGroup);
            app.userinterfaceButton.Enable = 'off';
            app.userinterfaceButton.Text = 'user interface';
            app.ClassifiertemplateButtonGroup = uibuttongroup(app.ClassificationTab);
            app.ClassifiertemplateButtonGroup.Title = 'Classifier template';
            app.readfromUIButton = uiradiobutton(app.ClassifiertemplateButtonGroup);
            app.readfromUIButton.Text = 'read from UI';
            app.readfromUIButton.Value = true;
            app.featuremapfileButton = uiradiobutton(app.ClassifiertemplateButtonGroup);
            app.featuremapfileButton.Text = 'feature map file';
            app.DatasetPanel = uipanel(app.UIFigure);
            app.DatasetPanel.Title = 'Dataset';
            app.GridLayout7 = uigridlayout(app.DatasetPanel);
            app.ICRpathEditFieldLabel = uilabel(app.GridLayout7);
            app.ICRpathEditFieldLabel.Text = 'ICR path';
            app.ICRpathEditField = uieditfield(app.GridLayout7, 'text');
            app.ICRpathEditField.Editable = 'off';
            app.CurrentdatasetEditFieldLabel = uilabel(app.GridLayout7);
            app.CurrentdatasetEditFieldLabel.Text = 'Current dataset';
            app.CurrentdatasetEditField = uieditfield(app.GridLayout7, 'text');
            app.CurrentdatasetEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentdatasetEditFieldValueChanged, true);
            app.CurrentdatasetEditField.Editable = 'off';
            app.ChangeButton = uibutton(app.GridLayout7, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.OpenICRsButton = uibutton(app.GridLayout7, 'push');
            app.OpenICRsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenICRsButtonPushed, true);
            app.OpenICRsButton.Text = 'Open ICRs';
            app.OpendatasetButton = uibutton(app.GridLayout7, 'push');
            app.OpendatasetButton.ButtonPushedFcn = createCallbackFcn(app, @OpendatasetButtonPushed, true);
            app.OpendatasetButton.Text = 'Open dataset';
            app.autoregenicrs = uicheckbox(app.GridLayout7);
            app.autoregenicrs.Text = 'auto regen';
            app.GenerateICRsbaseButton = uibutton(app.GridLayout7, 'push');
            app.GenerateICRsbaseButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateICRsbaseButtonPushed, true);
            app.GenerateICRsbaseButton.Text = 'Generate ICRs base';
            app.ShowICRSCheckBox = uicheckbox(app.GridLayout7);
            app.ShowICRSCheckBox.Text = 'Show graphics';
            app.ICRListBox = uilistbox(app.GridLayout7);
            app.ICRListBox.Items = {};
            app.ICRListBox.Multiselect = 'on';
            app.ICRListBox.Value = {};
            app.ObjectsButtonGroup = uibuttongroup(app.GridLayout7);
            app.ObjectsButtonGroup.Title = 'Objects';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end